classdef Robot_arm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        File                        matlab.ui.container.Menu
        PortMenu                    matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
        LogTextAreaLabel            matlab.ui.control.Label
        LogTextArea                 matlab.ui.control.TextArea
        Button                      matlab.ui.control.Button
        AccelerationEditFieldLabel  matlab.ui.control.Label
        AccelerationEditField       matlab.ui.control.NumericEditField
        DurationEditFieldLabel      matlab.ui.control.Label
        DurationEditField           matlab.ui.control.NumericEditField
        AngleEditFieldLabel         matlab.ui.control.Label
        AngleEditField              matlab.ui.control.NumericEditField
        SerialPortTextAreaLabel     matlab.ui.control.Label
        SerialPortTextArea          matlab.ui.control.TextArea
        ReadSerialButton            matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        AccelerationEditField_2     matlab.ui.control.NumericEditField
        AngleEditField_2Label       matlab.ui.control.Label
        AngleEditField_2            matlab.ui.control.NumericEditField
        DurationEditField_2Label    matlab.ui.control.Label
        DurationEditField_2         matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        Property % Description
        serial_obj;
        target = 0; 
        current = 0;
        current_2 = 0; 
        iTextBox = 0;
    end 
    methods (Access = private)
        
               
       function DisplayTextArea(app,message,isError)
            if isError == 1
                app.iTextBox = app.iTextBox + 1;
               
                str_cell = sprintf("%.3d:Error : %s",app.iTextBox,message);
                app.LogTextArea.Value(app.iTextBox) = cellstr(str_cell);
            else 
                app.iTextBox = app.iTextBox + 1;
               
                str_cell = sprintf("%.3d:Succes: %s",app.iTextBox,message);
                app.LogTextArea.Value(app.iTextBox) = cellstr(str_cell);
            end 
       end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             ports = serialportlist;
             for c = 1: length(ports)
                m =  uimenu(app.PortMenu,'Text',ports(c));
             end 
              app.serial_obj = serialport("COM4",9600); 
              

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            accel = app.AccelerationEditField.Value;
            duration = app.DurationEditField.Value; 
            angle = app.AngleEditField.Value - app.current
            str = "M1" 
            sign = "+";
            velocity = 0.5*(accel*duration -sqrt((accel*duration)^2-4*accel * abs(angle)));
            if(angle <0 )
                sign = "-";
            end
            if  velocity > 1.0 && isreal(velocity)
                str = str + num2str(duration,'%02.f') + num2str(accel, '%02.f') + ...
                sign + num2str(abs(app.AngleEditField.Value), '%03.f')
                disp_str = "Velocity is " + velocity;
                DisplayTextArea(app,disp_str,0);
                write(app.serial_obj,str,'char');
                app.current = app.AngleEditField.Value;
            else 
                disp_str = "Velocity is to small", "Velocity is " + velocity;
                DisplayTextArea(app,disp_str,1);
                disp("invalid");    
            end 
        end

        % Menu selected function: PortMenu
        function PortMenuSelected(app, event)
           app.PortMenu.Children(1)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: ReadSerialButton
        function ReadSerialButtonPushed(app, event)
            if app.serial_obj.NumBytesAvailable > 0 
                data = read(app.serial_obj,app.serial_obj.NumBytesAvailable, 'char');
                app.SerialPortTextArea.Value = data
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            accel = app.AccelerationEditField_2.Value;
            duration = app.DurationEditField_2.Value; 
            angle = app.AngleEditField_2.Value - app.current_2;
            str = "M2"
            sign = "+";
            velocity = 0.5*(accel*duration -sqrt((accel*duration)^2-4*accel * abs(angle)));
            if(angle <0 )
                sign = "-";
            end
            if  velocity > 1.0 && isreal(velocity)
                str = str + num2str(duration,'%02.f') + num2str(accel, '%02.f') + ...
                sign + num2str(abs(app.AngleEditField_2.Value), '%03.f');
                disp_str = "Velocity is " + velocity;
                DisplayTextArea(app,disp_str,0);
                write(app.serial_obj,str,'char');
                app.current_2 = app.AngleEditField_2.Value;
            else 
                disp_str = "Velocity is to small", "Velocity is " + velocity;
                DisplayTextArea(app,disp_str,1);
                disp("invalid");    
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.PortMenu = uimenu(app.File);
            app.PortMenu.MenuSelectedFcn = createCallbackFcn(app, @PortMenuSelected, true);
            app.PortMenu.Text = 'Port';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.AccelerationEditFieldLabel = uilabel(app.UIFigure);
            app.AccelerationEditFieldLabel.Text = 'Acceleration';
            app.AccelerationEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.SerialPortTextAreaLabel = uilabel(app.UIFigure);
            app.SerialPortTextAreaLabel.Text = 'Serial Port';
            app.SerialPortTextArea = uitextarea(app.UIFigure);
            app.ReadSerialButton = uibutton(app.UIFigure, 'push');
            app.ReadSerialButton.ButtonPushedFcn = createCallbackFcn(app, @ReadSerialButtonPushed, true);
            app.ReadSerialButton.Text = 'Read Serial';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.AccelerationEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditField_2Label = uilabel(app.UIFigure);
            app.AngleEditField_2Label.Text = 'Angle';
            app.AngleEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditField_2Label = uilabel(app.UIFigure);
            app.DurationEditField_2Label.Text = 'Duration';
            app.DurationEditField_2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Robot_arm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end